classdef project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        PlotOptionsLabel                matlab.ui.control.Label
        MinimumMagnitudeSpinnerLabel    matlab.ui.control.Label
        MinimumMagnitudeSpinner         matlab.ui.control.Spinner
        PlotAllButton                   matlab.ui.control.Button
        TrainModelButton                matlab.ui.control.Button
        ShowstrongestearthquakesSwitchLabel  matlab.ui.control.Label
        ShowstrongestearthquakesSwitch  matlab.ui.control.Switch
        AnalysisLabel                   matlab.ui.control.Label
        GetPredictionButton             matlab.ui.control.Button
        HideMapButton                   matlab.ui.control.StateButton
        ModeDropDownLabel               matlab.ui.control.Label
        ModeDropDown                    matlab.ui.control.DropDown
        ModelInfoButton                 matlab.ui.control.Button
        RightPanel                      matlab.ui.container.Panel
        EarthquakeMapPanel              matlab.ui.container.Panel
        Image                           matlab.ui.control.Image
        RichterScaleLabel               matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Get data and button values
    properties (Access = public)
        model = load('quakeModel.mat').modelUpdated;
        lastTraining = load('lastTraining.dat');
        lastAccuracy = load('lastAccuracy.dat');
        quake = getQuake('2020-01-01', datestr(load('lastTraining.dat'), 'yyyy-mm-dd'));
        min_magnitude;
        plot_mode;
        strongest;
        hide;
        text;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            gx = geoaxes(app.EarthquakeMapPanel);
            geoplot(gx, [-90 90], [-180 180]);
        end

        % Value changed function: MinimumMagnitudeSpinner
        function MinimumMagnitudeSpinnerValueChanged(app, event)
            if isempty(app.MinimumMagnitudeSpinner.Value)
                app.min_magnitude = 0;
            else
                app.min_magnitude = app.MinimumMagnitudeSpinner.Value; 
            end
        end

        % Button pushed function: PlotAllButton
        function PlotAllButtonPushed(app, event)
            % To prevent label overlapping, destroy previous pane, image, and label after
            % every plotting and create new ones
            delete(app.EarthquakeMapPanel);
            app.EarthquakeMapPanel = uipanel(app.RightPanel);
            app.EarthquakeMapPanel.TitlePosition = 'centertop';
            app.EarthquakeMapPanel.Title = 'Earthquake Map';
            app.EarthquakeMapPanel.FontWeight = 'bold';
            app.EarthquakeMapPanel.FontSize = 16;
            app.EarthquakeMapPanel.Position = [6 7 826 627];
            
            delete(app.Image);
            app.Image = uiimage(app.EarthquakeMapPanel);
            app.Image.Position = [761 58 56 477];
            app.Image.ImageSource = 'colorbar.png';
            
            delete(app.RichterScaleLabel);
            app.RichterScaleLabel = uilabel(app.EarthquakeMapPanel);
            app.RichterScaleLabel.HorizontalAlignment = 'center';
            app.RichterScaleLabel.FontWeight = 'bold';
            app.RichterScaleLabel.Position = [753 538 62 28];
            app.RichterScaleLabel.Text = {'Richter''s'; 'Scale'};
            
            if ~(app.min_magnitude > 0)
                app.min_magnitude = 0;
            end
            % Get data, with the minimum magnitude set by spinner
            if (isempty(app.min_magnitude))
                app.min_magnitude = 0;
            end
            data = getCoordinates(app.quake, app.min_magnitude);
            % Delete rows containg only zeros
            data(~any(data,2), :) = [];
            % Create geoaxes object
            gx = geoaxes(app.EarthquakeMapPanel);
            % Check if hide_button value is equal to 0 or unchanged
            if ((isempty(app.hide)) || (app.hide == 0))
                % If initial value of plot_mode is empty or 1, plot a
                % scatterplot, otherwise, density plot.
                if (isempty(app.plot_mode)) || (strcmp(app.plot_mode, '1'))
                    % Add a dummy earthquake with magnitude 0 to make
                    % the Richter's scale work properly
                    plot = geoscatter(gx, [data(:,1); 0], [data(:,2); 0], 20, [data(:,3); 0], 'Filled');
                    % Add magnitude label to points on the map
                    plot.DataTipTemplate.DataTipRows(end+1) = dataTipTextRow('Magnitude', data(:, 3));
                else
                    geodensityplot(gx, data(:,1), data(:,2), 'Radius', 5e5);  
                end
            end
            % Mark storngest earthquakes on map if button is checked
            if strcmp(app.strongest, 'On')
                    gx.NextPlot = 'add';
                    strongest_quakes = flipud(sortrows(data, 3));
                    plot2 = geoscatter(gx, strongest_quakes(1:10, 1), strongest_quakes(1:10, 2), 100, 'magenta', 'Filled');
                    plot2.DataTipTemplate.DataTipRows(end+1) = dataTipTextRow('Magnitude', strongest_quakes(1:10, 3));
            end   
        end

        % Value changed function: ModeDropDown
        function ModeDropDownValueChanged(app, event)
            app.plot_mode = app.ModeDropDown.Value; 
        end

        % Value changed function: ShowstrongestearthquakesSwitch
        function ShowstrongestearthquakesSwitchValueChanged(app, event)
            app.strongest = app.ShowstrongestearthquakesSwitch.Value;
        end

        % Value changed function: HideMapButton
        function HideMapButtonValueChanged(app, event)
            app.hide = app.HideMapButton.Value;    
        end

        % Button pushed function: TrainModelButton
        function TrainModelButtonPushed(app, event)
            % Get the training data with getTrainingset function
            % Train model and record date 
            % Import model 
            % If the user clicks the button another time, check if the date
            % condition is met
            currentDate = datetime('now');
            if etime(datevec(currentDate), app.lastTraining)/3600 < 24
                uiwait(msgbox('Not enought time passed since last training.','Training Failed', 'modal'));
            else               
                [updatedData, num_addedRecords, new_accuracy] = getUpdatedata(app.quake, app.lastTraining, currentDate, app.model);
                getModel(updatedData);
                uiwait(msgbox(sprintf('Model trained succsesfully.\nNumber of added records: %d', num_addedRecords, 'Success')));
                getModelParameters(currentDate, new_accuracy);               
            end
        end

        % Button pushed function: GetPredictionButton
        function GetPredictionButtonPushed(app, event)
            prompt = {'Latitude', 'Longitude'};
            title = 'Enter Coordinates';
            dims = [1 35];
            userCoords = inputdlg(prompt, title, dims);
            lat = str2double(userCoords{1});
            lon = str2double(userCoords{2});
            while (((lat < -180) || (lat > 180)) || ((lon < -90) || (lon > 90)))
                    uiwait(msgbox('Invalid coordinates. Please try again.', 'Invalid Coordinates'));
                    userCoords = inputdlg(prompt, title, dims);
                    lat = str2double(userCoords{1});
                    lon = str2double(userCoords{2});
            end
            prediction = predict(app.model, [lat lon]);
            switch (prediction)
                case 0
                    prediction = "Between 0 and 2";
                case 1
                    prediction = "Between 2 and 4";
                case 2
                    prediction = "Greater or equal to 4";
                otherwise
                    prediction = "Earthquake occurence unlikely.";
            end
            uiwait(msgbox(sprintf('Latitude: %.3f\nLongitude: %.3f\nMagnitude Prediction: %s',...
                                   lat, lon, prediction), 'Earthquake Magnitude Prediction'));
        end

        % Callback function
        function TextAreaValueChanged(app, event)
            app.text = app.TextArea.Value;
        end

        % Button pushed function: ModelInfoButton
        function ModelInfoButtonPushed(app, event)
            records = app.model.NumObservations;
            accuracy = load('lastAccuracy.dat');
            lastTrain = datestr(app.lastTraining, 'yyyy-mm-dd');
            nextAvailable = getNextTraining(app.lastTraining);
            
            uiwait(msgbox(sprintf('Current Data Records: %d\nLatest Accuracy: %.2f%s\nLatest Update: %s\nNext Update Availabe In: %s',...
                                  records, accuracy, "%", lastTrain, nextAvailable), 'Model Information'));
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {640, 640};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {216, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.PlotOptionsLabel = uilabel(app.LeftPanel);
            app.PlotOptionsLabel.Text = 'Plot Options';
            app.MinimumMagnitudeSpinnerLabel = uilabel(app.LeftPanel);
            app.MinimumMagnitudeSpinnerLabel.Text = 'Minimum Magnitude';
            app.MinimumMagnitudeSpinner = uispinner(app.LeftPanel);
            app.MinimumMagnitudeSpinner.Limits = [0 6];
            app.MinimumMagnitudeSpinner.ValueChangedFcn = createCallbackFcn(app, @MinimumMagnitudeSpinnerValueChanged, true);
            app.PlotAllButton = uibutton(app.LeftPanel, 'push');
            app.PlotAllButton.ButtonPushedFcn = createCallbackFcn(app, @PlotAllButtonPushed, true);
            app.PlotAllButton.Text = 'Plot All';
            app.TrainModelButton = uibutton(app.LeftPanel, 'push');
            app.TrainModelButton.ButtonPushedFcn = createCallbackFcn(app, @TrainModelButtonPushed, true);
            app.TrainModelButton.Text = 'Train Model';
            app.ShowstrongestearthquakesSwitchLabel = uilabel(app.LeftPanel);
            app.ShowstrongestearthquakesSwitchLabel.Text = 'Show strongest earthquakes';
            app.ShowstrongestearthquakesSwitch = uiswitch(app.LeftPanel, 'slider');
            app.ShowstrongestearthquakesSwitch.ValueChangedFcn = createCallbackFcn(app, @ShowstrongestearthquakesSwitchValueChanged, true);
            app.AnalysisLabel = uilabel(app.LeftPanel);
            app.AnalysisLabel.Text = 'Analysis';
            app.GetPredictionButton = uibutton(app.LeftPanel, 'push');
            app.GetPredictionButton.ButtonPushedFcn = createCallbackFcn(app, @GetPredictionButtonPushed, true);
            app.GetPredictionButton.Text = 'Get Prediction';
            app.HideMapButton = uibutton(app.LeftPanel, 'state');
            app.HideMapButton.ValueChangedFcn = createCallbackFcn(app, @HideMapButtonValueChanged, true);
            app.HideMapButton.Text = 'Hide Map';
            app.ModeDropDownLabel = uilabel(app.LeftPanel);
            app.ModeDropDownLabel.Text = 'Mode';
            app.ModeDropDown = uidropdown(app.LeftPanel);
            app.ModeDropDown.Items = {'Scatterplot', 'Density Plot'};
            app.ModeDropDown.ItemsData = {'1', '2'};
            app.ModeDropDown.ValueChangedFcn = createCallbackFcn(app, @ModeDropDownValueChanged, true);
            app.ModeDropDown.Value = '1';
            app.ModelInfoButton = uibutton(app.LeftPanel, 'push');
            app.ModelInfoButton.ButtonPushedFcn = createCallbackFcn(app, @ModelInfoButtonPushed, true);
            app.ModelInfoButton.Text = 'Model Info';
            app.RightPanel = uipanel(app.GridLayout);
            app.EarthquakeMapPanel = uipanel(app.RightPanel);
            app.EarthquakeMapPanel.Title = 'Earthquake Map';
            app.Image = uiimage(app.EarthquakeMapPanel);
            app.Image.ImageSource = 'colorbar.png';
            app.RichterScaleLabel = uilabel(app.EarthquakeMapPanel);
            app.RichterScaleLabel.Text = {'Richter'; 'Scale'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = project

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end